classdef etchasketch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        XKnobLabel          matlab.ui.control.Label
        XKnob               matlab.ui.control.Knob
        YKnobLabel          matlab.ui.control.Label
        YKnob               matlab.ui.control.Knob
        ColorDropDownLabel  matlab.ui.control.Label
        ColorDropDown       matlab.ui.control.DropDown
        ShakeButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            plot(app.UIAxes,app.XKnob.Value,app.YKnob.Value,...
                'linewidth',2,'color',[.2 .2 .2]);
            xlim(app.UIAxes,[0 100]);
            ylim(app.UIAxes,[0 100]);
        end

        % Value changing function: YKnob
        function YKnobValueChanging(app, event)
            changingValue = event.Value;
            img = app.UIAxes.Children(1);
            img.XData(end+1)=app.XKnob.Value;
            img.YData(end+1)=changingValue;
        end

        % Value changing function: XKnob
        function XKnobValueChanging(app, event)
            changingValue = event.Value;
            img = app.UIAxes.Children(1);
            img.YData(end+1)=app.YKnob.Value;
            img.XData(end+1)=changingValue;
        end

        % Button pushed function: ShakeButton
        function ShakeButtonPushed(app, event)
            img=app.UIAxes.Children(1);
            mybeep=randn(15000,1);
            p=audioplayer(mybeep,60000);
            ocolor=img.Color;
            for n=1:3
                play(p);
                img.Color=mean([img.Color;app.UIAxes.BackgroundColor]);
                pause(.4)
            end
            img.YData=nan;
            img.XData=nan;
            img.Color=ocolor;
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.UIAxes.Children(1).Color=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Etch-A-Sketch')
            app.XKnobLabel = uilabel(app.UIFigure);
            app.XKnobLabel.Text = 'X';
            app.XKnob = uiknob(app.UIFigure, 'continuous');
            app.XKnob.ValueChangingFcn = createCallbackFcn(app, @XKnobValueChanging, true);
            app.XKnob.Value = 50;
            app.YKnobLabel = uilabel(app.UIFigure);
            app.YKnobLabel.Text = 'Y';
            app.YKnob = uiknob(app.UIFigure, 'continuous');
            app.YKnob.ValueChangingFcn = createCallbackFcn(app, @YKnobValueChanging, true);
            app.YKnob.Value = 50;
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Black', 'Red', 'Blue', 'Green'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Black';
            app.ShakeButton = uibutton(app.UIFigure, 'push');
            app.ShakeButton.ButtonPushedFcn = createCallbackFcn(app, @ShakeButtonPushed, true);
            app.ShakeButton.Text = 'Shake';
        end
    end

    methods (Access = public)

        % Construct app
        function app = etchasketch

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end